classdef Speed_ctrl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        StartButton            matlab.ui.control.Button
        Send1Button            matlab.ui.control.Button
        CloseButton            matlab.ui.control.Button
        RPM0255EditFieldLabel  matlab.ui.control.Label
        RPM0255EditField       matlab.ui.control.NumericEditField
        Send2Button            matlab.ui.control.Button
        dsPIC33FJ128GP802SPEEDCONTROLLabel  matlab.ui.control.Label
        SliderLabel            matlab.ui.control.Label
        Slider                 matlab.ui.control.Slider
        JosMolinaLabel         matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        PruebaButton           matlab.ui.control.Button
        AvisosLabel            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            server=tcpip('192.168.1.160',900,'NetworkRole','server');
            app.Label.Text = "Conectando con ESP-01";
            fopen(server);
            app.Label.Text = "Conectado";
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.Label.Text = "Cerrando servidor";
            pause(2);
            fclose(server);
            app.Label.Text = "Servidor cerrado";
            echotcpip('off')
        end

        % Value changed function: RPM0255EditField
        function RPM0255EditFieldValueChanged(app, event)
            value_field = app.RPM0255EditField.Value;
            ref_text = char(value_field);
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value_slider = app.Slider.Value;
            value_slider = int8((value_slider/100)*255);
            ref_slider = char(value_slider);
        end

        % Button pushed function: Send1Button
        function Send1ButtonPushed(app, event)
            for i=0:5
                fwrite(server,ref_text);
                fprintf(ref_text);
                pause(1);
            end
        end

        % Button pushed function: Send2Button
        function Send2ButtonPushed(app, event)
            for i=0:5
                fwrite(server,ref_slider);
                fprintf(ref_slider);
                pause(1);
            end
        end

        % Button pushed function: PruebaButton
        function PruebaButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.Send1Button = uibutton(app.UIFigure, 'push');
            app.Send1Button.ButtonPushedFcn = createCallbackFcn(app, @Send1ButtonPushed, true);
            app.Send1Button.Text = 'Send1';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.RPM0255EditFieldLabel = uilabel(app.UIFigure);
            app.RPM0255EditFieldLabel.Text = 'RPM 0-255';
            app.RPM0255EditField = uieditfield(app.UIFigure, 'numeric');
            app.RPM0255EditField.Limits = [0 255];
            app.RPM0255EditField.ValueDisplayFormat = '%.0f';
            app.RPM0255EditField.ValueChangedFcn = createCallbackFcn(app, @RPM0255EditFieldValueChanged, true);
            app.Send2Button = uibutton(app.UIFigure, 'push');
            app.Send2Button.ButtonPushedFcn = createCallbackFcn(app, @Send2ButtonPushed, true);
            app.Send2Button.Text = 'Send2';
            app.dsPIC33FJ128GP802SPEEDCONTROLLabel = uilabel(app.UIFigure);
            app.dsPIC33FJ128GP802SPEEDCONTROLLabel.Text = {'dsPIC33FJ128GP802'; 'SPEED CONTROL'};
            app.SliderLabel = uilabel(app.UIFigure);
            app.SliderLabel.Text = '1/100';
            app.Slider = uislider(app.UIFigure);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.JosMolinaLabel = uilabel(app.UIFigure);
            app.JosMolinaLabel.Text = 'José Molina';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.PruebaButton = uibutton(app.UIFigure, 'push');
            app.PruebaButton.ButtonPushedFcn = createCallbackFcn(app, @PruebaButtonPushed, true);
            app.PruebaButton.Text = 'Prueba';
            app.AvisosLabel = uilabel(app.UIFigure);
            app.AvisosLabel.Text = 'Avisos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Speed_ctrl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end